classdef PreprocessApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        GridLayout                   matlab.ui.container.GridLayout
        LargestConnectedComponentCheckBox  matlab.ui.control.CheckBox
        FillHolesCheckBox            matlab.ui.control.CheckBox
        GreenWeightEditField         matlab.ui.control.NumericEditField
        GreenWeightEditFieldLabel    matlab.ui.control.Label
        BlueWeightEditField          matlab.ui.control.NumericEditField
        BlueWeightEditFieldLabel     matlab.ui.control.Label
        RedWeightEditField           matlab.ui.control.NumericEditField
        RedWeightEditFieldLabel      matlab.ui.control.Label
        BinarizeCheckBox             matlab.ui.control.CheckBox
        StDevofKernelEditField       matlab.ui.control.NumericEditField
        StDevofKernelEditFieldLabel  matlab.ui.control.Label
        LightBackgroundCheckBox      matlab.ui.control.CheckBox
        PreprocessButton             matlab.ui.control.Button
        ThresholdCheckBox            matlab.ui.control.CheckBox
        GaussianSmoothingCheckBox    matlab.ui.control.CheckBox
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: PreprocessButton
        function PreprocessButtonPushed(app, event)
            %Preprocess the data

            topFolder=uigetdir(pwd,'Please select your data folder');
            topFolder=erase(topFolder,[pwd,filesep]);


                if app.GaussianSmoothingCheckBox.Value && app.BinarizeCheckBox.Value

                    % Recursively get all folders inside topFolder
                    allFolders = genpath(topFolder);  % Returns a colon-separated list of paths
                    
                    % Split into individual folder paths
                    folderList = strsplit(allFolders, pathsep);
                    
                    % Loop through each folder
                    for i = 1:length(folderList)
                        folderPath = folderList{i};
                        
                        if isempty(folderPath)
                            continue; %if folder is empty, pass to next iteration of loop
                        end
                    
                        % Get png and jpg files in this folder only (non-recursive)
                        pngFiles = dir(fullfile(folderPath, '*.png'));
                        jpgFiles = dir(fullfile(folderPath, '*.jpg'));
                        files=[pngFiles;jpgFiles]; %combine into one array
                    
                        % Skip folders with no PNGs
                        if isempty(files)
                            continue;
                        end
                        
                        %Keeping track of indices in loop
                        file_count=0;
                        
                        %Loop through files in one folder
                        for file=files'
                        
                            file_count=file_count+1;
                            
                            %Keeping track of path of image for storage
                            imgPath=fullfile(file.folder,file.name);
                
                            %Read and store image
                            imdata=imread(imgPath);

                            %grayscale conversion
                            R=app.RedWeightEditField.Value;
                            G=app.GreenWeightEditField.Value;
                            B=app.BlueWeightEditField.Value;
                            imdata=R*imdata(:,:,1)+B*imdata(:,:,2)+G*imdata(:,:,3);
                            imdata=im2double(imdata); %Converts from 0-255 to 0-1 scale

                            if app.LightBackgroundCheckBox.Value
                                imdata=1-imdata; %convert to negative
                            end
                            
    
                            %% Filter first
                            stdev=app.StDevofKernelEditField.Value;
                            imdata=imgaussfilt(imdata,stdev);

                            % %Initialize image to filter
                            % [N,M]=size(imdata);
                            % 
                            % %Length of moving (forwards and backwards) windows
                            % %Averaging over multiple window lengths takes much more time
                            % m1=4;
                            % m2=8;
                            % m3=16;
                            % 
                            % %Weighting of variance
                            % p=1;
                            % 
                            % for i=1:3 %repeated application of filter
                            % 
                            % 
                            %     for row=1:N
                            %         x=imdata(row,:);
                            %         [y1,~,~]=ckfilter(x,m1,p);
                            %         [y2,~,~]=ckfilter(x,m2,p);
                            %         [y3,~,~]=ckfilter(x,m3,p);
                            %         y=(1/3)*y1+(1/3)*y2+(1/3)*y3; %average over windows
                            % 
                            %         imdata(row,:)=y;
                            %     end
                            % 
                            %     for col=1:M
                            %         x=imdata(:,col);
                            %         [y1,~,~]=ckfilter(x,m1,p);
                            %         [y2,~,~]=ckfilter(x,m2,p);
                            %         [y3,~,~]=ckfilter(x,m3,p);
                            %         y=(1/3)*y1+(1/3)*y2+(1/3)*y3; %average over windows
                            %         imdata(:,col)=y;
                            %     end
                            % end
        
                            %% Thresholding second
                            % Calculate a 255-bin histogram for the image
                            [counts, ~] = imhist(imdata,255);
                            % Compute a global threshold using the histogram counts
                            T = otsuthresh(counts);
                            % Create a binary image using the computed threshold
                            BinImdata = imbinarize(imdata, T);

                            if app.FillHolesCheckBox.Value
                                BinImdata=imfill(BinImdata,'holes');
                            end

                            if app.LargestConnectedComponentCheckBox.Value
                                BinImdata=bwareafilt(BinImdata,1);
                            end
                            

                            if app.ThresholdCheckBox.Value
                                imdata=imdata.*BinImdata;
                            else
                                imdata=BinImdata;
                            end
        
                            imageFolder='preprocessed';
                
                            if ~exist(imageFolder,'dir')
                                mkdir(imageFolder)
                            end
                            
                            %pwd for "path to working directory"
                            
                            %Gets path removing topFolder/ (e.g. angiosperms/)
                            relativeFolder=erase(folderPath, topFolder);
                
                            %Create output file path
                            imageOutput=fullfile(imageFolder,relativeFolder);
                
                            %Constructing output folders
                            if ~exist(imageOutput,'dir')
                                mkdir(imageOutput)
                            end
        
                            
                            %Naming files and giving them paths
                            [~,name,~]=fileparts(file.name);
        
                            imageTitle=[name,'_proc.jpg'];
                
                            imageFilePath=fullfile(imageOutput,imageTitle);
                
                            %Writing files to appropriate path
                            imwrite(imdata,imageFilePath); %image file

                        end %End loop through files

                    end %End loop through folders

                elseif app.BinarizeCheckBox.Value
                    % Recursively get all folders inside topFolder
                    allFolders = genpath(topFolder);  % Returns a colon-separated list of paths
                    
                    % Split into individual folder paths
                    folderList = strsplit(allFolders, pathsep);
                    
                    % Loop through each folder
                    for i = 1:length(folderList)
                        folderPath = folderList{i};
                        
                        if isempty(folderPath)
                            continue; %if folder is empty, pass to next iteration of loop
                        end
                    
                        % Get png and jpg files in this folder only (non-recursive)
                        pngFiles = dir(fullfile(folderPath, '*.png'));
                        jpgFiles = dir(fullfile(folderPath, '*.jpg'));
                        files=[pngFiles;jpgFiles]; %combine into one array
                    
                        % Skip folders with no PNGs
                        if isempty(files)
                            continue;
                        end
                        
                        %Keeping track of indices in loop
                        file_count=0;
                        
                        %Loop through files in one folder

                            for file=files'
                        
                            file_count=file_count+1;
                            
                            %Keeping track of path of image for storage
                            imgPath=fullfile(file.folder,file.name);
                
                            %Read and store image
                            imdata=imread(imgPath);
                            

                            %grayscale conversion
                            R=app.RedWeightEditField.Value;
                            G=app.GreenWeightEditField.Value;
                            B=app.BlueWeightEditField.Value;
                            imdata=R*imdata(:,:,1)+B*imdata(:,:,2)+G*imdata(:,:,3);
                            imdata=im2double(imdata); %Converts from 0-255 to 0-1 scale

                            if app.LightBackgroundCheckBox.Value
                                imdata=1-imdata; %convert to negative
                            end
                            

                            %Thresholding%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

                            % Calculate a 255-bin histogram for the image
                            [counts, ~] = imhist(imdata,255);
                            % Compute a global threshold using the histogram counts
                            T = otsuthresh(counts);
                            % Create a binary image using the computed threshold
                            BinImdata = imbinarize(imdata, T);

                            if app.FillHolesCheckBox.Value
                                BinImdata=imfill(BinImdata,'holes');
                            end

                            if app.LargestConnectedComponentCheckBox.Value
                                BinImdata=bwareafilt(BinImdata,1);
                            end
                            

                            if app.ThresholdCheckBox.Value
                                imdata=imdata.*BinImdata;
                            else
                                imdata=BinImdata;
                            end
        
                            imageFolder='preprocessed';
                
                            if ~exist(imageFolder,'dir')
                                mkdir(imageFolder)
                            end

                            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        
                            imageFolder='preprocessed';
                
                            if ~exist(imageFolder,'dir')
                                mkdir(imageFolder)
                            end
                            
                            %pwd for "path to working directory"
                            
                            %Gets path removing topFolder/ (e.g. angiosperms/)
                            relativeFolder=erase(folderPath, topFolder);
                
                            %Create output file path
                            imageOutput=fullfile(imageFolder,relativeFolder);
                
                            %Constructing output folders
                            if ~exist(imageOutput,'dir')
                                mkdir(imageOutput)
                            end
        
                            
                            %Naming files and giving them paths
                            [~,name,~]=fileparts(file.name);
        
                            imageTitle=[name,'_proc.jpg'];
                
                            imageFilePath=fullfile(imageOutput,imageTitle);
                
                            %Writing files to appropriate path
                            imwrite(imdata,imageFilePath); %image file
                            
                            end
                       
                    end %Loop through folders   

                elseif app.GaussianSmoothingCheckBox.Value
                    % Recursively get all folders inside topFolder
                    allFolders = genpath(topFolder);  % Returns a colon-separated list of paths
                    
                    % Split into individual folder paths
                    folderList = strsplit(allFolders, pathsep);
                    
                    % Loop through each folder
                    for i = 1:length(folderList)
                        folderPath = folderList{i};
                        
                        if isempty(folderPath)
                            continue; %if folder is empty, pass to next iteration of loop
                        end
                    
                        % Get png and jpg files in this folder only (non-recursive)
                        pngFiles = dir(fullfile(folderPath, '*.png'));
                        jpgFiles = dir(fullfile(folderPath, '*.jpg'));
                        files=[pngFiles;jpgFiles]; %combine into one array
                    
                        % Skip folders with no PNGs
                        if isempty(files)
                            continue;
                        end
                        
                        %Keeping track of indices in loop
                        file_count=0;
                        
                        %Loop through files in one folder

                            for file=files'
                        
                            file_count=file_count+1;
                            
                            %Keeping track of path of image for storage
                            imgPath=fullfile(file.folder,file.name);
                
                            %Read and store image
                            imdata=imread(imgPath);
                            

                            %grayscale conversion
                            R=app.RedWeightEditField.Value;
                            G=app.GreenWeightEditField.Value;
                            B=app.BlueWeightEditField.Value;
                            imdata=R*imdata(:,:,1)+B*imdata(:,:,2)+G*imdata(:,:,3);
                            imdata=im2double(imdata); %converts from 0-255 to 0-1 scale

        
                            if app.LightBackgroundCheckBox.Value
                                imdata=1-imdata; %convert to negative
                            end

                            %Smoothing%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                            stdev=app.StDevofKernelEditField.Value;
                            imdata=imgaussfilt(imdata,stdev);

                            % %Initialize image to filter
                            % [N,M]=size(imdata);
                            % 
                            % %Length of moving (forwards and backwards) windows
                            % %Averaging over multiple window lengths takes much more time
                            % m1=4;
                            % m2=8;
                            % m3=16;
                            % 
                            % %Weighting of variance
                            % p=1;
                            % 
                            % for i=1:3 %repeated application of filter
                            % 
                            % 
                            %     for row=1:N
                            %         x=imdata(row,:);
                            %         [y1,~,~]=ckfilter(x,m1,p);
                            %         [y2,~,~]=ckfilter(x,m2,p);
                            %         [y3,~,~]=ckfilter(x,m3,p);
                            %         y=(1/3)*y1+(1/3)*y2+(1/3)*y3; %average over windows
                            % 
                            %         imdata(row,:)=y;
                            %     end
                            % 
                            %     for col=1:M
                            %         x=imdata(:,col);
                            %         [y1,~,~]=ckfilter(x,m1,p);
                            %         [y2,~,~]=ckfilter(x,m2,p);
                            %         [y3,~,~]=ckfilter(x,m3,p);
                            %         y=(1/3)*y1+(1/3)*y2+(1/3)*y3; %average over windows
                            %         imdata(:,col)=y;
                            %     end
                            % end
                            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        
                            imageFolder='preprocessed';
                
                            if ~exist(imageFolder,'dir')
                                mkdir(imageFolder)
                            end
                            
                            %pwd for "path to working directory"
                            
                            %Gets path removing topFolder/ (e.g. angiosperms/)
                            relativeFolder=erase(folderPath, topFolder);
                
                            %Create output file path
                            imageOutput=fullfile(imageFolder,relativeFolder);
                
                            %Constructing output folders
                            if ~exist(imageOutput,'dir')
                                mkdir(imageOutput)
                            end
        
                            
                            %Naming files and giving them paths
                            [~,name,~]=fileparts(file.name);
        
                            imageTitle=[name,'_proc.jpg'];
                
                            imageFilePath=fullfile(imageOutput,imageTitle);
                
                            %Writing files to appropriate path
                            imwrite(imdata,imageFilePath); %image file
                            
                            end
                       
                    end %Loop through folders 

                else %Only grayscale (and negative) conversion
                    
                    % Recursively get all folders inside topFolder
                    allFolders = genpath(topFolder);  % Returns a colon-separated list of paths
                    
                    % Split into individual folder paths
                    folderList = strsplit(allFolders, pathsep);
                    
                    % Loop through each folder
                    for i = 1:length(folderList)
                        folderPath = folderList{i};
                        
                        if isempty(folderPath)
                            continue; %if folder is empty, pass to next iteration of loop
                        end
                    
                        % Get png and jpg files in this folder only (non-recursive)
                        pngFiles = dir(fullfile(folderPath, '*.png'));
                        jpgFiles = dir(fullfile(folderPath, '*.jpg'));
                        files=[pngFiles;jpgFiles]; %combine into one array
                    
                        % Skip folders with no PNGs
                        if isempty(files)
                            continue;
                        end
                        
                        %Keeping track of indices in loop
                        file_count=0;
                        
                        %Loop through files in one folder

                            for file=files'
                        
                            file_count=file_count+1;
                            
                            %Keeping track of path of image for storage
                            imgPath=fullfile(file.folder,file.name);
                
                            %Read and store image
                            imdata=imread(imgPath);
                            

                            %grayscale conversion
                            R=app.RedWeightEditField.Value;
                            G=app.GreenWeightEditField.Value;
                            B=app.BlueWeightEditField.Value;
                            imdata=R*imdata(:,:,1)+B*imdata(:,:,2)+G*imdata(:,:,3);
                            imdata=im2double(imdata); %converts from 0-255 to 0-1 scale

        
                            if app.LightBackgroundCheckBox.Value
                                imdata=1-imdata; %convert to negative
                            end


                            imageFolder='preprocessed';
                
                            if ~exist(imageFolder,'dir')
                                mkdir(imageFolder)
                            end
                            
                            %pwd for "path to working directory"
                            
                            %Gets path removing topFolder/ (e.g. angiosperms/)
                            relativeFolder=erase(folderPath, topFolder);
                
                            %Create output file path
                            imageOutput=fullfile(imageFolder,relativeFolder);
                
                            %Constructing output folders
                            if ~exist(imageOutput,'dir')
                                mkdir(imageOutput)
                            end
        
                            
                            %Naming files and giving them paths
                            [~,name,~]=fileparts(file.name);
        
                            imageTitle=[name,'_proc.jpg'];
                
                            imageFilePath=fullfile(imageOutput,imageTitle);
                
                            %Writing files to appropriate path
                            imwrite(imdata,imageFilePath); %image file
                            
                            end
                       
                    end %Loop through folders


                end %End conditional statements
            
        end

        % Value changed function: GaussianSmoothingCheckBox
        function GaussianSmoothingCheckBoxValueChanged(app, event)
            value = app.GaussianSmoothingCheckBox.Value;
            if value
                app.StDevofKernelEditField.Enable=true;
            else
                app.StDevofKernelEditField.Enable=false;
            end
        end

        % Value changed function: BinarizeCheckBox
        function BinarizeCheckBoxValueChanged(app, event)
            value = app.BinarizeCheckBox.Value;
            if value
                app.ThresholdCheckBox.Enable=true;
                app.FillHolesCheckBox.Enable=true;
                app.LargestConnectedComponentCheckBox.Enable=true;
            else
                app.ThresholdCheckBox.Enable=false;
                app.FillHolesCheckBox.Enable=false;
                app.LargestConnectedComponentCheckBox.Enable=false;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.GaussianSmoothingCheckBox = uicheckbox(app.GridLayout);
            app.GaussianSmoothingCheckBox.ValueChangedFcn = createCallbackFcn(app, @GaussianSmoothingCheckBoxValueChanged, true);
            app.GaussianSmoothingCheckBox.Text = 'Gaussian Smoothing';
            app.ThresholdCheckBox = uicheckbox(app.GridLayout);
            app.ThresholdCheckBox.Enable = 'off';
            app.ThresholdCheckBox.Text = 'Threshold';
            app.PreprocessButton = uibutton(app.GridLayout, 'push');
            app.PreprocessButton.ButtonPushedFcn = createCallbackFcn(app, @PreprocessButtonPushed, true);
            app.PreprocessButton.Text = 'Preprocess';
            app.LightBackgroundCheckBox = uicheckbox(app.GridLayout);
            app.LightBackgroundCheckBox.Text = 'Light Background';
            app.StDevofKernelEditFieldLabel = uilabel(app.GridLayout);
            app.StDevofKernelEditFieldLabel.Enable = 'off';
            app.StDevofKernelEditFieldLabel.Text = 'St Dev of Kernel';
            app.StDevofKernelEditField = uieditfield(app.GridLayout, 'numeric');
            app.StDevofKernelEditField.Limits = [0 Inf];
            app.StDevofKernelEditField.Enable = 'off';
            app.StDevofKernelEditField.Value = 8;
            app.BinarizeCheckBox = uicheckbox(app.GridLayout);
            app.BinarizeCheckBox.ValueChangedFcn = createCallbackFcn(app, @BinarizeCheckBoxValueChanged, true);
            app.BinarizeCheckBox.Text = 'Binarize';
            app.RedWeightEditFieldLabel = uilabel(app.GridLayout);
            app.RedWeightEditFieldLabel.Text = 'Red Weight';
            app.RedWeightEditField = uieditfield(app.GridLayout, 'numeric');
            app.RedWeightEditField.Value = 0.299;
            app.BlueWeightEditFieldLabel = uilabel(app.GridLayout);
            app.BlueWeightEditFieldLabel.Text = 'Blue Weight';
            app.BlueWeightEditField = uieditfield(app.GridLayout, 'numeric');
            app.BlueWeightEditField.Value = 0.587;
            app.GreenWeightEditFieldLabel = uilabel(app.GridLayout);
            app.GreenWeightEditFieldLabel.Text = 'Green Weight';
            app.GreenWeightEditField = uieditfield(app.GridLayout, 'numeric');
            app.GreenWeightEditField.Value = 0.114;
            app.FillHolesCheckBox = uicheckbox(app.GridLayout);
            app.FillHolesCheckBox.Enable = 'off';
            app.FillHolesCheckBox.Text = 'Fill "Holes"';
            app.LargestConnectedComponentCheckBox = uicheckbox(app.GridLayout);
            app.LargestConnectedComponentCheckBox.Enable = 'off';
            app.LargestConnectedComponentCheckBox.Text = 'Largest Connected Component';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PreprocessApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end